classdef amygdala_conn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        workingdirectoryTextAreaLabel  matlab.ui.control.Label
        workingdirectoryTextArea       matlab.ui.control.TextArea
        selectworkingdirectoryButton   matlab.ui.control.Button
        WDTextAreaLabel                matlab.ui.control.Label
        dicomtoniftiCheckBox           matlab.ui.control.CheckBox
        estimateconnectivityButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           app.MainApp=mainapp; 
        end

        % Button pushed function: estimateconnectivityButton
        function estimateconnectivityButtonPushed(app, event)
  mat = load('amygdala_default.mat');
  cd(char(app.workingdirectoryTextArea.Value));
  mat.Cfg.WorkingDir = char(app.workingdirectoryTextArea.Value);
  mat.Cfg.DataProcessDir = char(app.workingdirectoryTextArea.Value);
  mat.Cfg.CalFC.ROIDef = {which('aal3_amygdala_mask.nii')};
  
  
  if app.dicomtoniftiCheckBox.Value == 1
  directory_content_all = dir('FunRaw');
  directory_content = directory_content_all(3:length(directory_content_all));
  directory_content_cell = struct2cell(directory_content);
  mat.Cfg.SubjectID = directory_content_cell(1,:);
  mat.Cfg.StartingDirName = 'FunRaw';
  mat.Cfg.IsNeedConvertFunDCM2IMG = 1;
  mat.Cfg.IsNeedConvertT1DCM2IMG = 1;
  else 
  directory_content_all = dir('FunImg');
  directory_content = directory_content_all(3:length(directory_content_all));
  directory_content_cell = struct2cell(directory_content);
  mat.Cfg.SubjectID = directory_content_cell(1,:);
  mat.Cfg.StartingDirName = 'FunImg';
  mat.Cfg.IsNeedConvertFunDCM2IMG = 0;
  mat.Cfg.IsNeedConvertT1DCM2IMG = 0;
  end
  
  save('parameter.mat','mat')
  DPARSFA_run(mat.Cfg);
           
        end

        % Button pushed function: selectworkingdirectoryButton
        function selectworkingdirectoryButtonPushed(app, event)
            app.workingdirectoryTextArea.Value = uigetdir();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
             app.MainApp.estimateamygdalaconnectivityButton.Enable = "on";
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.workingdirectoryTextAreaLabel = uilabel(app.UIFigure);
            app.workingdirectoryTextAreaLabel.Text = 'working directory';
            app.workingdirectoryTextArea = uitextarea(app.UIFigure);
            app.selectworkingdirectoryButton = uibutton(app.UIFigure, 'push');
            app.selectworkingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectworkingdirectoryButtonPushed, true);
            app.selectworkingdirectoryButton.Text = 'select working directory';
            app.WDTextAreaLabel = uilabel(app.UIFigure);
            app.WDTextAreaLabel.Text = 'which contains both ''FunRaw''/''FunImg'' and ''T1Raw''/''T1Img''';
            app.dicomtoniftiCheckBox = uicheckbox(app.UIFigure);
            app.dicomtoniftiCheckBox.Text = 'dicom to nifti';
            app.estimateconnectivityButton = uibutton(app.UIFigure, 'push');
            app.estimateconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @estimateconnectivityButtonPushed, true);
            app.estimateconnectivityButton.Text = 'estimate connectivity';
        end
    end

    methods (Access = public)

        % Construct app
        function app = amygdala_conn(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end