classdef depth_mask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_mask       matlab.ui.Figure
        inputdepthofstimulationmmEditFieldLabel  matlab.ui.control.Label
        inputdepthofstimulationmmEditField  matlab.ui.control.NumericEditField
        imageTextAreaLabel  matlab.ui.control.Label
        imageTextArea       matlab.ui.control.TextArea
        select              matlab.ui.control.Button
        generate_mask       matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        file_m % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
        end

        % Button pushed function: select
        function selectButtonPushed(app, event)
            [app.file_m,path_m] = uigetfile('*.nii')
            app.imageTextArea.Value = strcat (path_m,app.file_m)
        end

        % Button pushed function: generate_mask
        function generate_maskButtonPushed(app, event)
            clearvars Length_X Length_Y Length_Z Del_X_Index Del_Y_Index Del_Z_Index
            Image1 = niftiread(char(app.imageTextArea.Value));
            Info1 = niftiinfo(char(app.imageTextArea.Value));
            Mask1 = single(Image1 ~= 0);
            niftiwrite(Mask1,strcat('mask_all_',app.file_m));


            for i = 1:Info1.ImageSize(2)
                for j = 1:Info1.ImageSize(3)
                    Length_X(i,j) = sum(Mask1(:,i,j));
                end
            end
            Max_X=max(Length_X,[],"all");
            
            for i = 1:Info1.ImageSize(1)
                for j = 1:Info1.ImageSize(3)
                    Length_Y(i,j) = sum(Mask1(i,:,j));
                end
            end
            Max_Y=max(Length_Y,[],"all");
            
            for i = 1:Info1.ImageSize(1)
                for j = 1:Info1.ImageSize(2)
                    Length_Z(i,j) = sum(Mask1(i,j,:));
                end
            end
            Max_Z=max(Length_Z,[],"all");


            Deep = app.inputdepthofstimulationmmEditField.Value ;
            Vox_Del_X = ceil((Info1.PixelDimensions(1)/Deep)*Max_X*2);
            Del_Interval_X = floor(Max_X/(Vox_Del_X-1));
            Vox_first_X = ceil((Info1.ImageSize(1)-Max_X)/2)+1;
            
            Del_X = Mask1;
            for i = 1:Vox_Del_X
                Del_X_Index(i) = Vox_first_X+(i-1)*(Del_Interval_X-1);
                Del_X(Del_X_Index(i),:,:) = [];
            end;
            
            Zero_X1 = zeros(ceil(Vox_Del_X/2),Info1.ImageSize(2),Info1.ImageSize(3));
            Zero_X2 = zeros(Vox_Del_X-ceil(Vox_Del_X/2),Info1.ImageSize(2),Info1.ImageSize(3));
            Resize_X = cat(1,Zero_X1,Del_X,Zero_X2);


            Vox_Del_Y = ceil((Info1.PixelDimensions(2)/Deep)*Max_Y*2);
            Del_Interval_Y = floor(Max_Y/(Vox_Del_Y-1));
            Vox_first_Y = ceil((Info1.ImageSize(2)-Max_Y)/2)+1;
            
            Del_Y = Resize_X;
            for i = 1:Vox_Del_Y
                Del_Y_Index(i) = Vox_first_Y+(i-1)*(Del_Interval_Y-1);
                Del_Y(:,Del_Y_Index(i),:) = [];
            end;
            
            Zero_Y1 = zeros(Info1.ImageSize(1),ceil(Vox_Del_Y/2),Info1.ImageSize(3));
            Zero_Y2 = zeros(Info1.ImageSize(1),Vox_Del_Y-ceil(Vox_Del_Y/2),Info1.ImageSize(3));
            Resize_Y = cat(2,Zero_Y1,Del_Y,Zero_Y2);


            Vox_Del_Z = ceil((Info1.PixelDimensions(3)/Deep)*Max_Z*2);
            Del_Interval_Z = floor(Max_Z/(Vox_Del_Z-1));
            Vox_first_Z = ceil((Info1.ImageSize(3)-Max_Z)/2)+1;
            
            Del_Z = Resize_Y;
            for i = 1:Vox_Del_Z
                Del_Z_Index(i) = Vox_first_Z+(i-1)*(Del_Interval_Z-1);
                Del_Z(:,:,Del_Y_Index(i)) = [];
            end;
            
            Zero_Z1 = zeros(Info1.ImageSize(1),Info1.ImageSize(2),ceil(Vox_Del_Z/2));
            Zero_Z2 = zeros(Info1.ImageSize(1),Info1.ImageSize(2),Vox_Del_Z-ceil(Vox_Del_Z/2));
            Resize_Z = cat(3,Zero_Z1,Del_Z,Zero_Z2);


            Deep_Mask = Mask1 - Resize_Z;
            niftiwrite(Deep_Mask,strcat('deep_mask_',app.file_m),Info1);
            
            Image1_Deep_Mask = Image1.*Deep_Mask;
            niftiwrite(Image1_Deep_Mask,strcat('depth_masked_',app.file_m),Info1);
            
            
        end

        % Close request function: UIFigure_mask
        function UIFigure_maskCloseRequest(app, event)
             % Enable the Options button in main app
             if isvalid(app.MainApp)
             app.MainApp.maskButton.Enable = "on";
             end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_mask = uifigure;
            app.UIFigure_mask.Name = 'UI Figure';
            app.UIFigure_mask.CloseRequestFcn = createCallbackFcn(app, @UIFigure_maskCloseRequest, true);
            app.inputdepthofstimulationmmEditFieldLabel = uilabel(app.UIFigure_mask);
            app.inputdepthofstimulationmmEditFieldLabel.Text = 'input depth of stimulation (mm)';
            app.inputdepthofstimulationmmEditField = uieditfield(app.UIFigure_mask, 'numeric');
            app.imageTextAreaLabel = uilabel(app.UIFigure_mask);
            app.imageTextAreaLabel.Text = 'image';
            app.imageTextArea = uitextarea(app.UIFigure_mask);
            app.select = uibutton(app.UIFigure_mask, 'push');
            app.select.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.select.Text = 'select image';
            app.generate_mask = uibutton(app.UIFigure_mask, 'push');
            app.generate_mask.ButtonPushedFcn = createCallbackFcn(app, @generate_maskButtonPushed, true);
            app.generate_mask.Text = 'generate mask';
        end
    end

    methods (Access = public)

        % Construct app
        function app = depth_mask(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_mask)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_mask)
        end
    end
end