classdef individual_amygdala_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_main               matlab.ui.Figure
        inversenormalizationButton  matlab.ui.control.Button
        maskButton                  matlab.ui.control.Button
        findmaxdifferencesButton    matlab.ui.control.Button
        estimateamygdalaconnectivityButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        InverseApp % dialog app
        MaskApp
        MaxApp 
        AmygdalaConnApp 
    end
    

    methods (Access = private)

        % Button pushed function: inversenormalizationButton
        function inversenormalizationButtonPushed(app, event)
            app.inversenormalizationButton.Enable = 'off';
            app.InverseApp = inverse_normalization(app);
        end

        % Button pushed function: maskButton
        function maskButtonButtonPushed(app, event)
            app.maskButton.Enable = 'off';
            app.MaskApp = depth_mask(app);
        end

        % Button pushed function: findmaxdifferencesButton
        function findmaxdifferencesButtonPushed(app, event)
            app.findmaxdifferencesButton.Enable = 'off';
            app.MaxApp = max_difference(app);
        end

        % Button pushed function: estimateamygdalaconnectivityButton
        function estimateamygdalaconnectivityButtonPushed(app, event)
            app.estimateamygdalaconnectivityButton.Enable = 'off';
            app.AmygdalaConnApp = amygdala_conn(app);
        end

        % Callback function
        function dipabiviewButtonPushed(app, event)
            DPABI_VIEW
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_main = uifigure;
            app.UIFigure_main.Name = 'Main Window';
            app.inversenormalizationButton = uibutton(app.UIFigure_main, 'push');
            app.inversenormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @inversenormalizationButtonPushed, true);
            app.inversenormalizationButton.Text = 'inverse normalization';
            app.maskButton = uibutton(app.UIFigure_main, 'push');
            app.maskButton.ButtonPushedFcn = createCallbackFcn(app, @maskButtonButtonPushed, true);
            app.maskButton.Text = 'mask based on the depth of stimulation';
            app.findmaxdifferencesButton = uibutton(app.UIFigure_main, 'push');
            app.findmaxdifferencesButton.ButtonPushedFcn = createCallbackFcn(app, @findmaxdifferencesButtonPushed, true);
            app.findmaxdifferencesButton.Text = 'find max differences';
            app.estimateamygdalaconnectivityButton = uibutton(app.UIFigure_main, 'push');
            app.estimateamygdalaconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @estimateamygdalaconnectivityButtonPushed, true);
            app.estimateamygdalaconnectivityButton.Text = 'estimate amygdala connectivity';
        end
    end

    methods (Access = public)

        % Construct app
        function app = individual_amygdala_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_main)
        end
    end
end