classdef inverse_normalization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_inverse            matlab.ui.Figure
        selectflowfileButton        matlab.ui.control.Button
        flowfileLabel               matlab.ui.control.Label
        flowfileTextArea            matlab.ui.control.TextArea
        imageTextAreaLabel          matlab.ui.control.Label
        imageTextArea               matlab.ui.control.TextArea
        selectimageButton           matlab.ui.control.Button
        inversenormalizationButton  matlab.ui.control.Button
        outcomeLabel                matlab.ui.control.Label
        selectspmpathButton         matlab.ui.control.Button
        spmpathTextAreaLabel        matlab.ui.control.Label
        spmpathTextArea             matlab.ui.control.TextArea
        spmpathTextAreaLabel_2      matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Main app object
    end
    
    methods (Access = public)  
        function appDesignerFunction(app)
          out = spm_dartel_invnorm(job);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
            
            if isempty(strfind(path,'\toolbox\DARTEL'))==0
               app.selectspmpathButton.Enable = 'off';
            else
               app.selectspmpathButton.Enable = 'on';
            end
        end

        % Callback function: flowfileTextArea, selectflowfileButton
        function selectflowfileButtonPushed(app, event)
            [file_u,path_u] = uigetfile('*u_*.nii')
            app.flowfileTextArea.Value = strcat (path_u,file_u)
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            [file_pro,path_pro] = uigetfile('*.nii')
            app.imageTextArea.Value = strcat (path_pro,file_pro)
        end

        % Button pushed function: inversenormalizationButton
        function inversenormalizationButtonPushed(app, event)
            job.flowfields = {char(app.flowfileTextArea.Value)};
            job.images = {char(app.imageTextArea.Value)}
            job.interp = 1
            job.K = 6
            
            out = spm_dartel_invnorm(job)
            
            if isempty(out)==0
              app.outcomeLabel.Text = out.files{1,1};
            else
              app.outcomeLabel.Text = 'failed';
            end
        end

        % Button pushed function: selectspmpathButton
        function selectspmpathButtonPushed(app, event)
            app.spmpathTextArea.Value = uigetdir()
            addpath(char(app.spmpathTextArea.Value))
            addpath(strcat(char(app.spmpathTextArea.Value),'\toolbox\DARTEL'))
        end

        % Close request function: UIFigure_inverse
        function UIFigure_inverseCloseRequest(app, event)
            % Enable the Options button in main app
             if isvalid(app.MainApp)
             app.MainApp.inversenormalizationButton.Enable = "on";
             end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_inverse = uifigure;
            app.UIFigure_inverse.Name = 'UI Figure';
            app.UIFigure_inverse.CloseRequestFcn = createCallbackFcn(app, @UIFigure_inverseCloseRequest, true);
            app.selectflowfileButton = uibutton(app.UIFigure_inverse, 'push');
            app.selectflowfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectflowfileButtonPushed, true);
            app.selectflowfileButton.Text = 'select flow file';
            app.flowfileLabel = uilabel(app.UIFigure_inverse);
            app.flowfileLabel.Text = 'flow file';
            app.flowfileTextArea = uitextarea(app.UIFigure_inverse);
            app.flowfileTextArea.ValueChangedFcn = createCallbackFcn(app, @selectflowfileButtonPushed, true);
            app.imageTextAreaLabel = uilabel(app.UIFigure_inverse);
            app.imageTextAreaLabel.Text = 'image';
            app.imageTextArea = uitextarea(app.UIFigure_inverse);
            app.selectimageButton = uibutton(app.UIFigure_inverse, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Text = 'select image';
            app.inversenormalizationButton = uibutton(app.UIFigure_inverse, 'push');
            app.inversenormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @inversenormalizationButtonPushed, true);
            app.inversenormalizationButton.Text = 'inverse normalization';
            app.outcomeLabel = uilabel(app.UIFigure_inverse);
            app.outcomeLabel.Text = 'outcome';
            app.selectspmpathButton = uibutton(app.UIFigure_inverse, 'push');
            app.selectspmpathButton.ButtonPushedFcn = createCallbackFcn(app, @selectspmpathButtonPushed, true);
            app.selectspmpathButton.Text = 'select spm path';
            app.spmpathTextAreaLabel = uilabel(app.UIFigure_inverse);
            app.spmpathTextAreaLabel.Text = 'spm path';
            app.spmpathTextArea = uitextarea(app.UIFigure_inverse);
            app.spmpathTextAreaLabel_2 = uilabel(app.UIFigure_inverse);
            app.spmpathTextAreaLabel_2.Text = 'select spm path if DARTEL is not found in the Matlab path';
        end
    end

    methods (Access = public)

        % Construct app
        function app = inverse_normalization(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_inverse)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_inverse)
        end
    end
end