classdef max_difference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        findmaxdifferencesButton     matlab.ui.control.Button
        increasedpositiveconnectivityCheckBox  matlab.ui.control.CheckBox
        increasednegativeconnectivityCheckBox  matlab.ui.control.CheckBox
        decreasedpositiveconnectivityCheckBox  matlab.ui.control.CheckBox
        decreasednegativeconnectivityCheckBox  matlab.ui.control.CheckBox
        increasedabsoluteconnectivityCheckBox  matlab.ui.control.CheckBox
        decreasedabsoluteconnectivityCheckBox  matlab.ui.control.CheckBox
        mindistancebetweennodesmmEditFieldLabel  matlab.ui.control.Label
        mindistancebetweennodesmmEditField  matlab.ui.control.NumericEditField
        numberofnodesEditFieldLabel  matlab.ui.control.Label
        numberofnodesEditField       matlab.ui.control.NumericEditField
        distanceheatmapPanel         matlab.ui.container.Panel
        selectnormButton             matlab.ui.control.Button
        normTextAreaLabel            matlab.ui.control.Label
        normTextArea                 matlab.ui.control.TextArea
        imageTextArea_2Label         matlab.ui.control.Label
        imageTextArea                matlab.ui.control.TextArea
        selectimageButton            matlab.ui.control.Button
        absolutechangesCheckBox      matlab.ui.control.CheckBox
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        fileA % Description
        fileB
        MainApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
        end

        % Value changed function: 
        % increasedpositiveconnectivityCheckBox
        function increasedpositiveconnectivityCheckBoxValueChanged(app, event)
            value_in_po = app.increasedpositiveconnectivityCheckBox.Value;
            if value_in_po == 1;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
            else
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
            end
        end

        % Value changed function: 
        % increasednegativeconnectivityCheckBox
        function increasednegativeconnectivityCheckBoxValueChanged(app, event)
            value_in_ne = app.increasednegativeconnectivityCheckBox.Value;
            if value_in_ne == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Value changed function: 
        % decreasedpositiveconnectivityCheckBox
        function decreasedpositiveconnectivityCheckBoxValueChanged(app, event)
            value_de_po = app.decreasedpositiveconnectivityCheckBox.Value;
            if value_de_po == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Value changed function: 
        % decreasednegativeconnectivityCheckBox
        function decreasednegativeconnectivityCheckBoxValueChanged(app, event)
            value_de_ne = app.decreasednegativeconnectivityCheckBox.Value;
            if value_de_ne == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Value changed function: 
        % increasedabsoluteconnectivityCheckBox
        function increasedabsoluteconnectivityCheckBoxValueChanged(app, event)
            value_in_ab = app.increasedabsoluteconnectivityCheckBox.Value;
            if value_in_ab == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Value changed function: 
        % decreasedabsoluteconnectivityCheckBox
        function decreasedabsoluteconnectivityCheckBoxValueChanged(app, event)
            value_de_ab = app.decreasedabsoluteconnectivityCheckBox.Value;
            if value_de_ab == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.absolutechangesCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.absolutechangesCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Callback function
        function generatedistanceheatmapButtonPushed(app, event)
            h = heatmap(app.distanceheatmapPanel,rand(10));
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            [app.fileA,pathA] = uigetfile('*.nii')
            app.imageTextArea.Value = strcat(pathA,app.fileA)
        end

        % Button pushed function: selectnormButton
        function selectnormButtonPushed(app, event)
            [app.fileB,pathB] = uigetfile('*.nii')
            app.normTextArea.Value = strcat(pathB,app.fileB)
        end

        % Value changed function: absolutechangesCheckBox
        function absolutechangesCheckBoxValueChanged(app, event)
            value_ch = app.absolutechangesCheckBox.Value;
            if value_ch == 1;
  app.increasedpositiveconnectivityCheckBox.Enable = 0;
  app.increasednegativeconnectivityCheckBox.Enable = 0;
  app.decreasedpositiveconnectivityCheckBox.Enable = 0;
  app.decreasednegativeconnectivityCheckBox.Enable = 0;
  app.increasedabsoluteconnectivityCheckBox.Enable = 0;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 0;
  app.findmaxdifferencesButton.Enable = 1;
else
  app.increasedpositiveconnectivityCheckBox.Enable = 1;
  app.increasednegativeconnectivityCheckBox.Enable = 1;
  app.decreasedpositiveconnectivityCheckBox.Enable = 1;
  app.decreasednegativeconnectivityCheckBox.Enable = 1;
  app.increasedabsoluteconnectivityCheckBox.Enable = 1;
  app.decreasedabsoluteconnectivityCheckBox.Enable = 1;
  app.findmaxdifferencesButton.Enable = 0;
end
        end

        % Button pushed function: findmaxdifferencesButton
        function findmaxdifferencesButtonPushed(app, event)
info_B = niftiinfo(char(app.normTextArea.Value));
imageA = niftiread(char(app.imageTextArea.Value));
vol_A = spm_vol(char(app.imageTextArea.Value));
imageB = niftiread(char(app.normTextArea.Value));
fileA_name = strsplit(char(app.fileA),'.');
fileB_name = strsplit(char(app.fileB),'.');  


if app.increasedpositiveconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  imageA_pos_mask = single(imageA > 0);
  A_B_increase_mask = single((imageA - imageB) > 0);
  A_B_increase_pos_mask = imageA_pos_mask.*A_B_increase_mask.*A_mask;
  niftiwrite(A_B_increase_pos_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_positive_connectivity_mask.nii'));
  A_B = (imageA - imageB).*A_B_increase_pos_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_positive.nii'),info_B);
end;


if app.increasednegativeconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  imageA_neg_mask = single(imageA < 0);
  A_B_increase_mask = single((imageA - imageB) < 0);
  A_B_increase_neg_mask = imageA_neg_mask.*A_B_increase_mask.*A_mask;
  niftiwrite(A_B_increase_neg_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_negative_connectivity_mask.nii'));
  A_B = (imageB - imageA).*A_B_increase_neg_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_negative.nii'),info_B);
end;


if app.decreasedpositiveconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  imageA_pos_mask = single(imageA > 0);
  A_B_decrease_mask = single((imageA - imageB) < 0);
  A_B_decrease_pos_mask = imageA_pos_mask.*A_B_decrease_mask.*A_mask;
  niftiwrite(A_B_decrease_pos_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_positive_connectivity_mask.nii'));
  A_B = (imageB - imageA).*A_B_decrease_pos_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_positive.nii'),info_B);
end;


if app.decreasednegativeconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  imageA_neg_mask = single(imageA < 0);
  A_B_decrease_mask = single((imageA - imageB)>0);
  A_B_decrease_neg_mask = imageA_neg_mask.*A_B_decrease_mask.*A_mask;
  niftiwrite(A_B_decrease_neg_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_negative_connectivity_mask.nii'));
  A_B = (imageA - imageB).*A_B_decrease_neg_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_negative.nii'),info_B);
end;


if app.increasedabsoluteconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0 );
  imageA_pos_mask = single(imageA > 0);
  A_B_increase_pos_mask = single((imageA - imageB) > 0) .* imageA_pos_mask.*A_mask;
  imageA_neg_mask = single(imageA < 0);
  A_B_increase_neg_mask = single((imageA - imageB) < 0) .* imageA_neg_mask.*A_mask;
  A_B_increase_ab_mask = A_B_increase_pos_mask + A_B_increase_neg_mask;
  niftiwrite(A_B_increase_ab_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_absolute_connectivity_mask.nii'));
  A_B = abs(imageA - imageB) .* A_B_increase_ab_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_increased_absolute.nii'),info_B);
end;


if app.decreasedabsoluteconnectivityCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  imageA_pos_mask = single(imageA > 0);
  A_B_decrease_pos_mask = single((imageA - imageB) < 0) .* imageA_pos_mask.*A_mask;
  imageA_neg_mask = single(imageA < 0);
  A_B_decrease_neg_mask = single((imageA - imageB) > 0) .* imageA_neg_mask.*A_mask;
  A_B_decrease_ab_mask = A_B_decrease_pos_mask + A_B_decrease_neg_mask;
  niftiwrite(A_B_decrease_ab_mask,strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_absolute_connectivity_mask.nii'));
  A_B = abs(imageA - imageB) .* A_B_decrease_ab_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_decreased_absolute.nii'),info_B);
end;


if app.absolutechangesCheckBox.Value == 1;
  A_mask = single(imageA ~=0);
  A_B = (abs(imageA - imageB)).*A_mask;
  niftiwrite(single(A_B),strcat(fileA_name{1},'_minus_',fileB_name{1},'_absolute_changes.nii'),info_B);
end;


reshape_A_B = reshape(A_B,1,[]);
[sort_reshape_A_B,linear_location_reshape_A_B] = sort(reshape_A_B,'descend');

clearvars X Y Z flag_N flag_N_N distance;
N = app.numberofnodesEditField.Value;
Dis = app.mindistancebetweennodesmmEditField.Value;
i = 1;

while i <= N;
  [X(i),Y(i),Z(i)] = ind2sub(size(A_B), linear_location_reshape_A_B(i));
  flag = 0;
  flag_N(i)=0;
  
  if i == 1;
     i = i + 1;
    continue;
  end;  
  
  for j=1:i-1;
    distance(i,j) = (abs(((X(i)-X(j))*info_B.PixelDimensions(1)))^2 + abs(((Y(i)-Y(j))*info_B.PixelDimensions(2)))^2 + abs(((Z(i)-Z(j))*info_B.PixelDimensions(3)))^2)^0.5;  
	distance(j,i) = distance(i,j);
	flag_N_N(i,j)=0;
	
	if distance(i,j) <= Dis ;
	   flag_N_N(i,j) = 1;
       flag_N(i) = 1;
	   
	  if flag_N(j) == 1;
        flag_N_N(i,j) = 0;
      end; 
    
	end;
	
  end;
  
  if sum(flag_N_N(i,:)) > 0;
    N = N + 1;
  end;
  
  i = i + 1;
end;


heatmap(app.distanceheatmapPanel, distance, 'ColorLimits', [Dis Dis*5]);	


table_data = cell(app.numberofnodesEditField.Value, 3);
coor = cell(1, app.numberofnodesEditField.Value);
mni = cell(1, app.numberofnodesEditField.Value);
struc1 = cell(1, app.numberofnodesEditField.Value);
struc2 = cell(1, app.numberofnodesEditField.Value);
atlas = load('TDdatabase.mat');
M =1;

for k = 1:length(X);
  
  if flag_N(k) == 1;
    continue;
  end;
  
  table_data{M,1} = M;
  coor{M} = [X(k),Y(k),Z(k)];
  mni{M} = cor2mni(coor{M},vol_A.mat);
  table_data{M,2} = mni{M};
  [struc1{M}, struc2{M}] = cuixuFindStructure(mni{M}, atlas.DB);
  table_data{M,3} = struc2{M}{6};
  M = M+1;
  
end;

app.UITable.Data = cell2table(table_data);


app.UITable.Data = cell2table(table);


mask = single(zeros(info_B.ImageSize));

for k = 1:length(X);
  
  if flag_N(k) == 1;
    continue;
  end;
  
  mask(X(k),Y(k),Z(k)) = 1;
end;


image_out = single(A_B .* mask);
niftiwrite(mask,strcat('mask_max_',string(length(flag_N)-sum(flag_N)),'_',fileA_name{1},'_minus_',fileB_name{1},'.nii'),info_B);
niftiwrite(image_out,strcat('max_',string(length(flag_N)-sum(flag_N)),'_',fileA_name{1},'_minus_',fileB_name{1},'.nii'),info_B);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Options button in main app
             if isvalid(app.MainApp)
             app.MainApp.findmaxdifferencesButton.Enable = "on";
             end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.findmaxdifferencesButton = uibutton(app.UIFigure, 'push');
            app.findmaxdifferencesButton.ButtonPushedFcn = createCallbackFcn(app, @findmaxdifferencesButtonPushed, true);
            app.findmaxdifferencesButton.Enable = 'off';
            app.findmaxdifferencesButton.Text = 'find max differences';
            app.increasedpositiveconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.increasedpositiveconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @increasedpositiveconnectivityCheckBoxValueChanged, true);
            app.increasedpositiveconnectivityCheckBox.Text = 'increased positive connectivity';
            app.increasednegativeconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.increasednegativeconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @increasednegativeconnectivityCheckBoxValueChanged, true);
            app.increasednegativeconnectivityCheckBox.Text = 'increased negative connectivity';
            app.decreasedpositiveconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.decreasedpositiveconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @decreasedpositiveconnectivityCheckBoxValueChanged, true);
            app.decreasedpositiveconnectivityCheckBox.Text = 'decreased positive connectivity';
            app.decreasednegativeconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.decreasednegativeconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @decreasednegativeconnectivityCheckBoxValueChanged, true);
            app.decreasednegativeconnectivityCheckBox.Text = 'decreased negative connectivity';
            app.increasedabsoluteconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.increasedabsoluteconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @increasedabsoluteconnectivityCheckBoxValueChanged, true);
            app.increasedabsoluteconnectivityCheckBox.Text = 'increased absolute connectivity';
            app.decreasedabsoluteconnectivityCheckBox = uicheckbox(app.UIFigure);
            app.decreasedabsoluteconnectivityCheckBox.ValueChangedFcn = createCallbackFcn(app, @decreasedabsoluteconnectivityCheckBoxValueChanged, true);
            app.decreasedabsoluteconnectivityCheckBox.Text = 'decreased absolute connectivity';
            app.mindistancebetweennodesmmEditFieldLabel = uilabel(app.UIFigure);
            app.mindistancebetweennodesmmEditFieldLabel.Text = 'min distance between nodes (mm)';
            app.mindistancebetweennodesmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mindistancebetweennodesmmEditField.Limits = [1 Inf];
            app.mindistancebetweennodesmmEditField.Value = 10;
            app.numberofnodesEditFieldLabel = uilabel(app.UIFigure);
            app.numberofnodesEditFieldLabel.Text = 'number of nodes';
            app.numberofnodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberofnodesEditField.Limits = [1 Inf];
            app.numberofnodesEditField.Value = 2;
            app.distanceheatmapPanel = uipanel(app.UIFigure);
            app.distanceheatmapPanel.Title = 'distance heatmap';
            app.selectnormButton = uibutton(app.UIFigure, 'push');
            app.selectnormButton.ButtonPushedFcn = createCallbackFcn(app, @selectnormButtonPushed, true);
            app.selectnormButton.Text = 'select norm';
            app.normTextAreaLabel = uilabel(app.UIFigure);
            app.normTextAreaLabel.Text = 'norm';
            app.normTextArea = uitextarea(app.UIFigure);
            app.imageTextArea_2Label = uilabel(app.UIFigure);
            app.imageTextArea_2Label.Text = 'image';
            app.imageTextArea = uitextarea(app.UIFigure);
            app.selectimageButton = uibutton(app.UIFigure, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Text = 'select image';
            app.absolutechangesCheckBox = uicheckbox(app.UIFigure);
            app.absolutechangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @absolutechangesCheckBoxValueChanged, true);
            app.absolutechangesCheckBox.Text = 'absolute changes';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'number'; 'MNI coordinates'; 'AAL atlas'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = max_difference(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end